classdef interface_final_G05 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        VelocidadedeRotacoRotaesporMinutorpmEditField  matlab.ui.control.NumericEditField
        VelocidadedeRotacoRotaesporMinutorpmEditFieldLabel  matlab.ui.control.Label
        InformaesdoGeradorSncronoLabel  matlab.ui.control.Label
        Vazio                           matlab.ui.container.ButtonGroup
        Procurar                        matlab.ui.control.Button
        TensaoTerminal                  matlab.ui.control.NumericEditField
        FrequenciadaRedeLabel           matlab.ui.control.Label
        ReatanciaSincrona               matlab.ui.control.NumericEditField
        ReatanciaSincronaLabel          matlab.ui.control.Label
        ResistenciadeArmadura           matlab.ui.control.NumericEditField
        ResistnciadeArmaduraLabel       matlab.ui.control.Label
        NumerodePolos                   matlab.ui.control.NumericEditField
        NumerodePolosEditFieldLabel     matlab.ui.control.Label
        TipodeFP                        matlab.ui.container.ButtonGroup
        Capacitivo                      matlab.ui.control.RadioButton
        Indutivo                        matlab.ui.control.RadioButton
        FP                              matlab.ui.control.NumericEditField
        FPEditFieldLabel                matlab.ui.control.Label
        TipodeLigacao                   matlab.ui.container.ButtonGroup
        Delta                           matlab.ui.control.RadioButton
        Y                               matlab.ui.control.RadioButton
        PotenciaAparente                matlab.ui.control.NumericEditField
        PotenciaAparenteMVaLabel        matlab.ui.control.Label
        CaracteristicaAVazio            matlab.ui.control.UIAxes
        CenterPanel                     matlab.ui.container.Panel
        TensodeTerminalpu_q01           matlab.ui.control.NumericEditField
        TensodeTerminalpuEditFieldLabel  matlab.ui.control.Label
        CorrentedeCampopu_q01           matlab.ui.control.NumericEditField
        CorrentedeCampopuEditFieldLabel  matlab.ui.control.Label
        CorrentedeArmadurapu_q01        matlab.ui.control.NumericEditField
        CorrentedeArmaduraLabel         matlab.ui.control.Label
        LimparGrafico2_q01              matlab.ui.control.Button
        PorcentagemdeCarga_q01          matlab.ui.control.Spinner
        PorcentagemdeCargaSpinnerLabel  matlab.ui.control.Label
        Questo1Label                    matlab.ui.control.Label
        Grafico2_q01                    matlab.ui.control.UIAxes
        Grafico1_q01                    matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        CorrentedeCampopu_q02           matlab.ui.control.NumericEditField
        CorrentedeCampopuEditField_2Label  matlab.ui.control.Label
        TensodeTerminalpu_q02           matlab.ui.control.NumericEditField
        TensodeTerminalpuEditFieldLabel_2  matlab.ui.control.Label
        CorrentedeArmadurapu_q02        matlab.ui.control.NumericEditField
        CorrentedeArmaduraLabel_2       matlab.ui.control.Label
        LimparGrafico1_q01              matlab.ui.control.Button
        CorrentedeCampoA_q02            matlab.ui.control.Spinner
        CorrentedeCampoASpinner_2Label  matlab.ui.control.Label
        Questo2Label                    matlab.ui.control.Label
        Grafico1_q02                    matlab.ui.control.UIAxes
        Grafico2_q02                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        File;
        If_x_Vt;
        Ea_temp;
        jXsIa_temp;
    end
    
    properties (Access = private)
        pot_aparente; % potência aparente do GS
        Xs; % reatância síncrona do GS
        Ra; % resistência de armadura do GS
        fp; % fator de potência (atrasado)
        fp_estado; % i - indutivo e c-capacitivo
        ligacao; % y - ligacao Y e d - ligacao delta
        num_polos; % Número de polos da máquina.
        freq_ele; % frequência elétrica em Hz.
        Vt;
        Vp;
        Vl;
        Ia_ref;
        Il_ref;
        Ea_ref;
    end
    
    
    methods (Access = public)
        
        function calc_op_nominal_gs( ...
            app, ...
            tensao_linha ...
        )        
            if app.ligacao == 'Y'
                app.Ia_ref = app.pot_aparente/(sqrt(3)*tensao_linha); %Fp = 1?
                app.Il_ref = app.Ia_ref;
            end

            if app.ligacao == "Delta"
                app.Ia_ref = app.pot_aparente/(3*tensao_linha);
                app.Il_ref = app.Ia_ref*sqrt(3);
            end
            
            app.Ea_ref = app.Xs*app.Ia_ref;
        end
    
        function calc_tensao_fase(app)
    
            if app.ligacao == 'Y'
                app.Vp = app.Vt/sqrt(3);
                app.Vl = app.Vt;
            end
            
            if app.ligacao == "Delta"
                app.Vp = app.Vt;
                app.Vl = app.Vt;
            end
        
        end

        function [Ea,jXsIa] = calc_tensao_induzida( ...
                app, ...
                tensao_fase, ...
                tensao_fase_angle, ...
                Ia, ...
                Ia_phase)

            if app.fp_estado == "Indutivo"
                Ia_phase = -1*Ia_phase;
            end
            if app.fp_estado == "Capacitivo"
                Ia_phase = abs(Ia_phase);
            end

            disp(Ia_phase)
            
            jXsIa= 1i*app.Xs*(Ia*cosd(Ia_phase)+ 1i*Ia*sind(Ia_phase));
            %app.jXsIa_temp
            Ea = tensao_fase*cosd(tensao_fase_angle) + 1i*tensao_fase*sind(tensao_fase_angle) + jXsIa;
        end
    end
    %end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pot_aparente = app.PotenciaAparente.Value; % potência aparente do GS
            app.Xs = app.ReatanciaSincrona.Value; % reatância síncrona do GS
            app.Ra = app.ResistenciadeArmadura.Value; % resistência de armadura do GS
            app.fp = app.FP.Value; % fator de potência
            app.fp_estado = app.TipodeFP.SelectedObject.Text; % i - indutivo e c-capacitivo
            app.ligacao = app.TipodeLigacao.SelectedObject.Text; % y - ligacao Y e d - ligacao delta
            app.Vt = app.TensaoTerminal.Value; % tensão de terminal do GS
            app.num_polos = app.NumerodePolos.Value; % Número de polos da máquina.
            app.freq_ele = 60;

            %% Inicialização Geral
            app.VelocidadedeRotacoRotaesporMinutorpmEditField.Value = (app.freq_ele*120)/app.num_polos;
            app.calc_op_nominal_gs(app.Vt);
            app.calc_tensao_fase();

            %% Inicialização Questão 1
            %app.calc_op_nominal_gs(tensao_linha, app.ligacao, app.pot_aparente, app.Xs)
            app.PorcentagemdeCarga_q01.Value = 100;

            [Ea,jXsIa]  = app.calc_tensao_induzida( ...
                app.Vp, ...
                0, ...
                app.Ia_ref, ...
                acosd(app.fp)...
            );
% 
%             Ea
%             jXsIa
            if app.fp_estado == "Indutivo"
                Ia_phase = -1*acosd(app.fp);
            end
            if app.fp_estado == "Capacitivo"
                Ia_phase = abs(acosd(app.fp));
            end

            quiver( ...
                app.Grafico1_q01, ...
                0*abs(Ea), ...
                0*imag(Ea), ...
                abs(Ea), ...
                imag(Ea), ...
                0, ...
                'g' ...
            );
            hold(app.Grafico1_q01,'on');
            quiver(app.Grafico1_q01,0*app.Vp,0*app.Vp,app.Vp,0*app.Vp,0,'b');
            quiver( ...
                app.Grafico1_q01, ...
                0*app.Vp, ...
                0*app.Vp, ...
                app.Ia_ref*app.fp, ...
                app.Ia_ref*sind(Ia_phase), ...
                0, ...
                'r' ...
            );
            quiver(app.Grafico1_q01,app.Vp,0*app.Vp,abs(jXsIa),imag(jXsIa),0,'m');
            %hold off;

            %% Inicialização Questão 2
            %app.calc_op_nominal_gs(tensao_linha, app.ligacao, app.pot_aparente, app.Xs)
            quiver( ...
                app.Grafico1_q02, ...
                0*abs(Ea), ...
                0*imag(Ea), ...
                abs(Ea), ...
                imag(Ea), ...
                0, ...
                'g' ...
            );
            hold(app.Grafico1_q02,'on');
            quiver(app.Grafico1_q02,0*app.Vp,0*app.Vp,app.Vp,0*app.Vp,0,'b');
            quiver( ...
                app.Grafico1_q02, ...
                0*app.Vp, ...
                0*app.Vp, ...
                app.Ia_ref*app.fp, ...
                app.Ia_ref*sind(Ia_phase), ...
                0, ...
                'r' ...
            );
            quiver(app.Grafico1_q02,app.Vp,0*app.Vp,abs(jXsIa),imag(jXsIa),0,'m');
            %hold off;
        end

        % Button pushed function: Procurar
        function ProcurarButtonPushed(app, event)
            [file,fpath] = uigetfile('./*.csv');
            app.File.Value = [fpath,file];
            ds = datastore(app.File.Value);

            app.If_x_Vt.Value = readall(ds);
            plot( ...
                app.CaracteristicaAVazio, ...
                app.If_x_Vt.Value.IF, ...
                app.If_x_Vt.Value.VT, ...
                "Color","b", ...
                'LineStyle','-.', ...
                'LineWidth',2 ...
            );
        end

        % Value changed function: NumerodePolos
        function NumerodePolosValueChanged(app, event)
            value = app.NumerodePolos.Value;
            app.VelocidadedeRotacoRotaesporMinutorpmEditField.Value = (app.freq_ele*120)/value;
        end

        % Callback function: FP, PotenciaAparente, ReatanciaSincrona, 
        % ...and 4 other components
        function TipodeLigacaoSelectionChanged(app, event)
            app.ligacao = app.TipodeLigacao.SelectedObject.Text;
            app.fp_estado = app.TipodeFP.SelectedObject.Text;
            app.pot_aparente = app.PotenciaAparente.Value;
            app.Xs = app.ReatanciaSincrona.Value;
            app.Ra = app.ResistenciadeArmadura.Value;
            app.fp = app.FP.Value;
            app.Vt = app.TensaoTerminal.Value;

            %% Re-inicialização Geral
            app.calc_op_nominal_gs(tensao_linha, app.ligacao, app.pot_aparente, app.Xs);
        end

        % Button pushed function: LimparGrafico2_q01
        function LimparGrafico2_q01ButtonPushed(app, event)
            cla(app.Grafico2_q01);
            cla(app.Grafico2_q02);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {599, 599, 599};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {599, 599};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 433};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CaracteristicaAVazio = uiaxes(app.LeftPanel);
            xlabel(app.CaracteristicaAVazio, 'Corrente de Campo (A)')
            ylabel(app.CaracteristicaAVazio, 'Tensão de Terminal (kV)')
            zlabel(app.CaracteristicaAVazio, 'Z')
            app.PotenciaAparenteMVaLabel = uilabel(app.LeftPanel);
            app.PotenciaAparenteMVaLabel.Text = 'Potencia Aparente (MVa)';
            app.PotenciaAparente = uieditfield(app.LeftPanel, 'numeric');
            app.PotenciaAparente.Limits = [0 Inf];
            app.PotenciaAparente.ValueChangedFcn = createCallbackFcn(app, @TipodeLigacaoSelectionChanged, true);
            app.PotenciaAparente.Value = 50;
            app.TipodeLigacao = uibuttongroup(app.LeftPanel);
            app.TipodeLigacao.SelectionChangedFcn = createCallbackFcn(app, @TipodeLigacaoSelectionChanged, true);
            app.TipodeLigacao.Title = 'Tipo de Ligação';
            app.Y = uiradiobutton(app.TipodeLigacao);
            app.Y.Text = 'Y';
            app.Y.Value = true;
            app.Delta = uiradiobutton(app.TipodeLigacao);
            app.Delta.Text = 'Delta';
            app.FPEditFieldLabel = uilabel(app.LeftPanel);
            app.FPEditFieldLabel.Text = 'FP';
            app.FP = uieditfield(app.LeftPanel, 'numeric');
            app.FP.Limits = [0 1];
            app.FP.ValueChangedFcn = createCallbackFcn(app, @TipodeLigacaoSelectionChanged, true);
            app.FP.Value = 0.9;
            app.TipodeFP = uibuttongroup(app.LeftPanel);
            app.TipodeFP.SelectionChangedFcn = createCallbackFcn(app, @TipodeLigacaoSelectionChanged, true);
            app.TipodeFP.Title = 'Tipo de FP';
            app.Indutivo = uiradiobutton(app.TipodeFP);
            app.Indutivo.Text = 'Indutivo';
            app.Indutivo.Value = true;
            app.Capacitivo = uiradiobutton(app.TipodeFP);
            app.Capacitivo.Text = 'Capacitivo';
            app.NumerodePolosEditFieldLabel = uilabel(app.LeftPanel);
            app.NumerodePolosEditFieldLabel.Text = {'Numero '; 'de Polos'};
            app.NumerodePolos = uieditfield(app.LeftPanel, 'numeric');
            app.NumerodePolos.Limits = [1 Inf];
            app.NumerodePolos.ValueChangedFcn = createCallbackFcn(app, @NumerodePolosValueChanged, true);
            app.NumerodePolos.Value = 4;
            app.ResistnciadeArmaduraLabel = uilabel(app.LeftPanel);
            app.ResistnciadeArmaduraLabel.Interpreter = 'html';
            app.ResistnciadeArmaduraLabel.Text = 'Resistência de Armadura';
            app.ResistenciadeArmadura = uieditfield(app.LeftPanel, 'numeric');
            app.ResistenciadeArmadura.Limits = [0 Inf];
            app.ResistenciadeArmadura.ValueChangedFcn = createCallbackFcn(app, @TipodeLigacaoSelectionChanged, true);
            app.ResistenciadeArmadura.Value = 0.2;
            app.ReatanciaSincronaLabel = uilabel(app.LeftPanel);
            app.ReatanciaSincronaLabel.Text = 'Reatancia Sincrona';
            app.ReatanciaSincrona = uieditfield(app.LeftPanel, 'numeric');
            app.ReatanciaSincrona.Limits = [0 Inf];
            app.ReatanciaSincrona.ValueChangedFcn = createCallbackFcn(app, @TipodeLigacaoSelectionChanged, true);
            app.ReatanciaSincrona.Value = 2.5;
            app.FrequenciadaRedeLabel = uilabel(app.LeftPanel);
            app.FrequenciadaRedeLabel.Text = 'Tensão de Terminal (kV)';
            app.TensaoTerminal = uieditfield(app.LeftPanel, 'numeric');
            app.TensaoTerminal.Limits = [0 Inf];
            app.TensaoTerminal.ValueChangedFcn = createCallbackFcn(app, @TipodeLigacaoSelectionChanged, true);
            app.TensaoTerminal.Value = 13.8;
            app.Vazio = uibuttongroup(app.LeftPanel);
            app.Vazio.Title = 'Caracteristica a Vazio';
            app.Procurar = uibutton(app.Vazio, 'push');
            app.Procurar.ButtonPushedFcn = createCallbackFcn(app, @ProcurarButtonPushed, true);
            app.Procurar.Text = 'Procurar';
            app.InformaesdoGeradorSncronoLabel = uilabel(app.LeftPanel);
            app.InformaesdoGeradorSncronoLabel.Text = 'Informações do Gerador Síncrono';
            app.VelocidadedeRotacoRotaesporMinutorpmEditFieldLabel = uilabel(app.LeftPanel);
            app.VelocidadedeRotacoRotaesporMinutorpmEditFieldLabel.Text = {'Velocidade de Rotacão'; 'Rotações por Minuto (rpm)'};
            app.VelocidadedeRotacoRotaesporMinutorpmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VelocidadedeRotacoRotaesporMinutorpmEditField.Editable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Grafico1_q01 = uiaxes(app.CenterPanel);
            title(app.Grafico1_q01, 'Title')
            xlabel(app.Grafico1_q01, 'X')
            ylabel(app.Grafico1_q01, 'Y')
            zlabel(app.Grafico1_q01, 'Z')
            app.Grafico2_q01 = uiaxes(app.CenterPanel);
            title(app.Grafico2_q01, 'Title')
            xlabel(app.Grafico2_q01, 'X')
            ylabel(app.Grafico2_q01, 'Y')
            zlabel(app.Grafico2_q01, 'Z')
            app.Questo1Label = uilabel(app.CenterPanel);
            app.Questo1Label.Text = 'Questão 1';
            app.PorcentagemdeCargaSpinnerLabel = uilabel(app.CenterPanel);
            app.PorcentagemdeCargaSpinnerLabel.Text = 'Porcentagem de Carga (%)';
            app.PorcentagemdeCarga_q01 = uispinner(app.CenterPanel);
            app.PorcentagemdeCarga_q01.Step = 10;
            app.PorcentagemdeCarga_q01.Limits = [0 100];
            app.PorcentagemdeCarga_q01.Value = 60;
            app.LimparGrafico2_q01 = uibutton(app.CenterPanel, 'push');
            app.LimparGrafico2_q01.ButtonPushedFcn = createCallbackFcn(app, @LimparGrafico2_q01ButtonPushed, true);
            app.LimparGrafico2_q01.Text = 'Limpar';
            app.CorrentedeArmaduraLabel = uilabel(app.CenterPanel);
            app.CorrentedeArmaduraLabel.Text = {'Corrente de'; 'Armadura (pu)'};
            app.CorrentedeArmadurapu_q01 = uieditfield(app.CenterPanel, 'numeric');
            app.CorrentedeArmadurapu_q01.Editable = 'off';
            app.CorrentedeCampopuEditFieldLabel = uilabel(app.CenterPanel);
            app.CorrentedeCampopuEditFieldLabel.Text = {'Corrente de'; 'Campo (pu)'};
            app.CorrentedeCampopu_q01 = uieditfield(app.CenterPanel, 'numeric');
            app.CorrentedeCampopu_q01.Editable = 'off';
            app.TensodeTerminalpuEditFieldLabel = uilabel(app.CenterPanel);
            app.TensodeTerminalpuEditFieldLabel.Text = {'Tensão de'; 'Terminal (pu)'};
            app.TensodeTerminalpu_q01 = uieditfield(app.CenterPanel, 'numeric');
            app.TensodeTerminalpu_q01.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.Grafico2_q02 = uiaxes(app.RightPanel);
            title(app.Grafico2_q02, 'Title')
            xlabel(app.Grafico2_q02, 'X')
            ylabel(app.Grafico2_q02, 'Y')
            zlabel(app.Grafico2_q02, 'Z')
            app.Grafico1_q02 = uiaxes(app.RightPanel);
            title(app.Grafico1_q02, 'Title')
            xlabel(app.Grafico1_q02, 'X')
            ylabel(app.Grafico1_q02, 'Y')
            zlabel(app.Grafico1_q02, 'Z')
            app.Questo2Label = uilabel(app.RightPanel);
            app.Questo2Label.Text = 'Questão 2';
            app.CorrentedeCampoASpinner_2Label = uilabel(app.RightPanel);
            app.CorrentedeCampoASpinner_2Label.Text = 'Corrente de Campo (A)';
            app.CorrentedeCampoA_q02 = uispinner(app.RightPanel);
            app.CorrentedeCampoA_q02.Limits = [0 100];
            app.CorrentedeCampoA_q02.Value = 6;
            app.LimparGrafico1_q01 = uibutton(app.RightPanel, 'push');
            app.LimparGrafico1_q01.Text = 'Limpar';
            app.CorrentedeArmaduraLabel_2 = uilabel(app.RightPanel);
            app.CorrentedeArmaduraLabel_2.Text = {'Corrente de'; 'Armadura (pu)'};
            app.CorrentedeArmadurapu_q02 = uieditfield(app.RightPanel, 'numeric');
            app.CorrentedeArmadurapu_q02.Editable = 'off';
            app.TensodeTerminalpuEditFieldLabel_2 = uilabel(app.RightPanel);
            app.TensodeTerminalpuEditFieldLabel_2.Text = {'Tensão de'; 'Terminal (pu)'};
            app.TensodeTerminalpu_q02 = uieditfield(app.RightPanel, 'numeric');
            app.TensodeTerminalpu_q02.Editable = 'off';
            app.CorrentedeCampopuEditField_2Label = uilabel(app.RightPanel);
            app.CorrentedeCampopuEditField_2Label.Text = {'Corrente de'; 'Campo (pu)'};
            app.CorrentedeCampopu_q02 = uieditfield(app.RightPanel, 'numeric');
            app.CorrentedeCampopu_q02.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface_final_G05

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end