classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FrequenciadaRedeEditField       matlab.ui.control.NumericEditField
        FrequenciadaRedeEditFieldLabel  matlab.ui.control.Label
        NumerodePolosEditField          matlab.ui.control.NumericEditField
        NumerodePolosEditFieldLabel     matlab.ui.control.Label
        ProcurarButton                  matlab.ui.control.Button
        ResistenciadeArmaduraEditField  matlab.ui.control.NumericEditField
        ResistenciadeArmaduraEditFieldLabel  matlab.ui.control.Label
        ReatanciaSincronaEditField      matlab.ui.control.NumericEditField
        ReatanciaSincronaEditFieldLabel  matlab.ui.control.Label
        PotenciaAparenteEditField       matlab.ui.control.NumericEditField
        PotenciaAparenteLabel           matlab.ui.control.Label
        FPEditField                     matlab.ui.control.NumericEditField
        FPEditFieldLabel                matlab.ui.control.Label
        TipodoFPButtonGroup             matlab.ui.container.ButtonGroup
        CapacitivoButton                matlab.ui.control.RadioButton
        IndutivoButton                  matlab.ui.control.RadioButton
        TipodoligaoButtonGroup          matlab.ui.container.ButtonGroup
        DeltaButton                     matlab.ui.control.RadioButton
        YButton                         matlab.ui.control.RadioButton
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        
        function app = calcula(app)
            
            %If_x_Vt = recebe tabela;
            s = size(If_x_Vt.VT);
                %% Inicaial
            for c = 8:s(1)
                Vt = If_x_Vt(c,:).VT;
                %disp(Vt)

                %% Cálculo - GS operando Nominalmente
                [Ia_ref,Il_ref,Ea_ref,Z_ref,Fp_ref,Vel_rpm] = calc_op_nominal_gs( ...
                    Vt, ...
                    app.TipodoligaoButtonGroup, ...
                    app.PotenciaAparenteEditField, ...
                    app.NumerodePolosEditField, ...
                    app.FrequenciadaRedeEditField, ...
                    app.ReatanciaSincronaEditField, ...
                    app.ResistenciadeArmaduraEditField);

                %% Tensão de Fase
                [Vp,Vl] = calc_tensao_fase(Vt,app.TipodoligaoButtonGroup);

                %% Cálculo da Corrente de Armadura
                [Ia,Ia_phase,Il,Il_phase] = calc_correntes(app.PotenciaAparenteEditField,Vl,app.FPEditField,app.TipodoFPButtonGroup,app.TipodoligaoButtonGroup);

                %% Tensão Induzida Interna do GS
                [Ea,jXsIa,Z] = calc_tensao_induzida(Vp,0,Ia,Ia_phase,app.ReatanciaSincronaEditField,app.ResistenciadeArmaduraEditField); % angulo de fase de Vp é 0
                disp(abs(Ea))

                %% Novo Fator de Potência
                fp = acos((Ea*sind(0-angle(Ea)))/(abs(Z)*Ia));

                %% Gráficos
%                 compass(abs(Ea),imag(Ea),'-.','g');
%                 hold on;
%                 compass(Vp,0,'b');
%                 compass(Ia*cosd(Ia_phase),Ia*sind(Ia_phase),'r');
%                 compass(abs(jXsIa),imag(jXsIa),'m');
%                 %compass(Ea,'-.','r')

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Selection changed function: TipodoFPButtonGroup, 
        % ...and 1 other component
        function TipodoligaoButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipodoligaoButtonGroup.SelectedObject;
            
        end

        % Value changed function: FPEditField, NumerodePolosEditField, 
        % ...and 3 other components
        function VTEditFieldValueChanged(app, event)
            app=calcula(app);
            app=geragrafico(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.TipodoligaoButtonGroup = uibuttongroup(app.UIFigure);
            app.TipodoligaoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodoligaoButtonGroupSelectionChanged, true);
            app.TipodoligaoButtonGroup.Title = 'Tipo do ligação';
            app.YButton = uiradiobutton(app.TipodoligaoButtonGroup);
            app.YButton.Text = 'Y';
            app.YButton.Value = true;
            app.DeltaButton = uiradiobutton(app.TipodoligaoButtonGroup);
            app.DeltaButton.Text = 'Delta';
            app.TipodoFPButtonGroup = uibuttongroup(app.UIFigure);
            app.TipodoFPButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodoligaoButtonGroupSelectionChanged, true);
            app.TipodoFPButtonGroup.Title = 'Tipo do FP';
            app.IndutivoButton = uiradiobutton(app.TipodoFPButtonGroup);
            app.IndutivoButton.Text = 'Indutivo';
            app.IndutivoButton.Value = true;
            app.CapacitivoButton = uiradiobutton(app.TipodoFPButtonGroup);
            app.CapacitivoButton.Text = 'Capacitivo';
            app.FPEditFieldLabel = uilabel(app.UIFigure);
            app.FPEditFieldLabel.Text = 'FP';
            app.FPEditField = uieditfield(app.UIFigure, 'numeric');
            app.FPEditField.Limits = [0 1];
            app.FPEditField.ValueChangedFcn = createCallbackFcn(app, @VTEditFieldValueChanged, true);
            app.FPEditField.Value = 0.9;
            app.PotenciaAparenteLabel = uilabel(app.UIFigure);
            app.PotenciaAparenteLabel.Text = {'Potencia '; 'Aparente'};
            app.PotenciaAparenteEditField = uieditfield(app.UIFigure, 'numeric');
            app.PotenciaAparenteEditField.ValueChangedFcn = createCallbackFcn(app, @VTEditFieldValueChanged, true);
            app.PotenciaAparenteEditField.Value = 13.8;
            app.ReatanciaSincronaEditFieldLabel = uilabel(app.UIFigure);
            app.ReatanciaSincronaEditFieldLabel.Text = {'Reatancia'; 'Sincrona'};
            app.ReatanciaSincronaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReatanciaSincronaEditField.ValueChangedFcn = createCallbackFcn(app, @VTEditFieldValueChanged, true);
            app.ReatanciaSincronaEditField.Value = 2.5;
            app.ResistenciadeArmaduraEditFieldLabel = uilabel(app.UIFigure);
            app.ResistenciadeArmaduraEditFieldLabel.Text = {'Resistencia'; 'de Armadura'};
            app.ResistenciadeArmaduraEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResistenciadeArmaduraEditField.ValueChangedFcn = createCallbackFcn(app, @VTEditFieldValueChanged, true);
            app.ResistenciadeArmaduraEditField.Value = 0.2;
            app.ProcurarButton = uibutton(app.UIFigure, 'push');
            app.ProcurarButton.Text = 'Procurar';
            app.NumerodePolosEditFieldLabel = uilabel(app.UIFigure);
            app.NumerodePolosEditFieldLabel.Text = {'Numero'; 'de Polos'};
            app.NumerodePolosEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumerodePolosEditField.ValueChangedFcn = createCallbackFcn(app, @VTEditFieldValueChanged, true);
            app.FrequenciadaRedeEditFieldLabel = uilabel(app.UIFigure);
            app.FrequenciadaRedeEditFieldLabel.Text = {'Frequencia'; 'da Rede'};
            app.FrequenciadaRedeEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end