classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FPSlider               matlab.ui.control.Slider
        FPSliderLabel          matlab.ui.control.Label
        UITable                matlab.ui.control.Table
        CarregarArquivoButton  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        File % Description
        If_x_Vt
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CarregarArquivoButton
        function CarregarArquivoButtonPushed(app, event)

            [file,fpath] = uigetfile('../*.csv');
            app.File.Value = [fpath,file];
            ds = datastore(app.File.Value);

            app.If_x_Vt.Value = readall(ds);
            %If_x_Vt
            %If_x_Vt = readtable('../caracteristica_a_vazio_gs.csv');
            app.UITable.Data=app.If_x_Vt.Value(:,2:3);
            plot(app.UIAxes_3,app.If_x_Vt.Value.IF,app.If_x_Vt.Value.VT,"Color","b",'LineWidth',2);
            plot(app.UIAxes_2,app.If_x_Vt.Value.IF,app.If_x_Vt.Value.VT/sqrt(3),"Color","b",'LineWidth',2);
            quiver( ...
                app.UIAxes, ...
                0, ...
                0, ...
                app.If_x_Vt.Value(6,:).IF, ...
                app.If_x_Vt.Value(6,:).IF*sind(acosd(app.FPSlider.Value)), ...
                0, ...
                'r' ...
            );
            hold(app.UIAxes,'on')
            quiver(app.UIAxes,0,0,app.If_x_Vt.Value(6,:).VT/1000,0,0,'b');
            hold(app.UIAxes_3,'on')
            hold(app.UIAxes_3,'on')
        end

        % Value changed function: FPSlider
        function FPSliderValueChanged(app, event)
            value_FP = app.FPSlider.Value;
            value_FP
            %app.If_x_Vt.Value(6,'IF')
            %app.If_x_Vt.Value(6,'VT')
            % tensão
            quiver( ...
                app.UIAxes, ...
                0, ...
                0, ...
                app.If_x_Vt.Value(6,:).IF, ...
                app.If_x_Vt.Value(6,:).IF*sind(acosd(value_FP)), ...
                0, ...
                'r' ...
            );
            hold(app.UIAxes,'on')
            quiver(app.UIAxes,0,0,app.If_x_Vt.Value(6,:).VT/1000,0,0,'b');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CarregarArquivoButton = uibutton(app.UIFigure, 'push');
            app.CarregarArquivoButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarArquivoButtonPushed, true);
            app.CarregarArquivoButton.Text = 'Carregar Arquivo';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Vt (V)'; 'IF (A)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true];
            app.UITable.ColumnEditable = true;
            app.FPSliderLabel = uilabel(app.UIFigure);
            app.FPSliderLabel.Text = 'FP';
            app.FPSlider = uislider(app.UIFigure);
            app.FPSlider.Limits = [-1 1];
            app.FPSlider.ValueChangedFcn = createCallbackFcn(app, @FPSliderValueChanged, true);
            app.FPSlider.Value = 0.9;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end